classdef gui_stl_slice_and_plot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        STLFileLabel                 matlab.ui.control.Label
        textfield_stl_filename       matlab.ui.control.EditField
        button_browse_stl_filename   matlab.ui.control.Button
        SliceheightEditFieldLabel    matlab.ui.control.Label
        numericField_slice_height    matlab.ui.control.NumericEditField
        SliceButton                  matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        ASCIIfileCheckBox            matlab.ui.control.CheckBox
        RotationangleEditFieldLabel  matlab.ui.control.Label
        RotationangleEditField       matlab.ui.control.NumericEditField
        RotateButton                 matlab.ui.control.Button
        RotationaxisButtonGroup      matlab.ui.container.ButtonGroup
        xButton                      matlab.ui.control.RadioButton
        yButton                      matlab.ui.control.RadioButton
        zButton                      matlab.ui.control.RadioButton
        ReadFileButton               matlab.ui.control.Button
        PanButton                    matlab.ui.control.Button
        ZoomButton                   matlab.ui.control.Button
        RotateButton_2               matlab.ui.control.Button
        UIAxes2                      matlab.ui.control.UIAxes
        PanButton_2                  matlab.ui.control.Button
        ZoomButton_2                 matlab.ui.control.Button
        RotateButton_3               matlab.ui.control.Button
        PlotdelayEditFieldLabel      matlab.ui.control.Label
        PlotdelayEditField           matlab.ui.control.NumericEditField
        XminEditFieldLabel           matlab.ui.control.Label
        XminEditField                matlab.ui.control.NumericEditField
        YminEditFieldLabel           matlab.ui.control.Label
        YminEditField                matlab.ui.control.NumericEditField
        ZminEditFieldLabel           matlab.ui.control.Label
        ZminEditField                matlab.ui.control.NumericEditField
        XmaxEditFieldLabel           matlab.ui.control.Label
        XmaxEditField                matlab.ui.control.NumericEditField
        YmaxEditFieldLabel           matlab.ui.control.Label
        YmaxEditField                matlab.ui.control.NumericEditField
        ZmaxEditFieldLabel           matlab.ui.control.Label
        ZmaxEditField                matlab.ui.control.NumericEditField
        XminEditField_2Label         matlab.ui.control.Label
        XminEditField_2              matlab.ui.control.NumericEditField
        YminEditField_2Label         matlab.ui.control.Label
        YminEditField_2              matlab.ui.control.NumericEditField
        ZminEditField_2Label         matlab.ui.control.Label
        ZminEditField_2              matlab.ui.control.NumericEditField
        XmaxEditField_2Label         matlab.ui.control.Label
        XmaxEditField_2              matlab.ui.control.NumericEditField
        YmaxEditField_2Label         matlab.ui.control.Label
        YmaxEditField_2              matlab.ui.control.NumericEditField
        ZmaxEditField_2Label         matlab.ui.control.Label
        ZmaxEditField_2              matlab.ui.control.NumericEditField
        ApplyLimitsButton            matlab.ui.control.Button
        ApplyLimitsButton_2          matlab.ui.control.Button
    end


    properties (Access = public)
        triangles % Triangles_original
        mod_triangles %triangles modified
        plot_vals_stl_data;
        ui1pan;
        ui1zoom;
        ui1rotate;
        ui2rotate;
        ui2zoom;
        ui2pan;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: button_browse_stl_filename
        function button_browse_stl_filenamePushed(app, event)
            [file, path] = uigetfile('*.stl');
            full_file_path_name = strcat(path,file);
            %app.textfield_stl_filename.Text = full_file_path_name;
            app.textfield_stl_filename.Value = full_file_path_name;            
        end

        % Button pushed function: SliceButton
        function SliceButtonPushed(app, event)
            %hold(app.UIAxes2,'on')
            slice_height = app.numericField_slice_height.Value;
            tic;[movelist, z_slices] = slice_stl_create_path(app.mod_triangles, slice_height);toc;
            plot_slices_app(app.UIAxes2, movelist, z_slices, app.PlotdelayEditField.Value);
            hold(app.UIAxes2,'off')
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            axis = app.RotationaxisButtonGroup.SelectedObject.Text;
            app.mod_triangles = rotate_stl(app.mod_triangles,axis,app.RotationangleEditField.Value);
            %v = reshape(app.mod_triangles(:,1:9)',3,size(app.mod_triangles,1)*3)';
            plot_stl_app(app.UIAxes,app.mod_triangles);
          
        end

        % Button pushed function: ReadFileButton
        function ReadFileButtonPushed(app, event)
             filename = app.textfield_stl_filename.Value;
            if app.ASCIIfileCheckBox.Value == 0
                app.triangles = read_binary_stl_file(filename);
                app.mod_triangles = app.triangles;
            else
                app.triangles = read_ascii_stl(filename,1);
                app.mod_triangles = app.triangles;
            end
            %v = reshape(app.mod_triangles(:,1:9)',3,size(app.mod_triangles,1)*3)';
            plot_stl_app(app.UIAxes, app.mod_triangles);   
            
        end

        % Button pushed function: PanButton
        function PanButtonPushed(app, event)
           pan(app.UIAxes,'on'); 
        end

        % Button pushed function: ZoomButton
        function ZoomButtonPushed(app, event)
            zoom(app.UIAxes,'on');
        end

        % Button pushed function: RotateButton_2
        function RotateButton_2Pushed(app, event)
            rotate3d(app.UIAxes,'on');
        end

        % Button pushed function: PanButton_2
        function PanButton_2Pushed(app, event)
            pan(app.UIAxes2,'on'); 
        end

        % Button pushed function: ZoomButton_2
        function ZoomButton_2Pushed(app, event)
            zoom(app.UIAxes2,'on');
        end

        % Button pushed function: RotateButton_3
        function RotateButton_3Pushed(app, event)
            rotate3d(app.UIAxes2,'on');
        end

        % Value changed function: XminEditField
        function XminEditFieldValueChanged(app, event)
            x_limits = app.UIAxes2.XLim;
            app.UIAxes2.XLim = [app.XminEditField.Value, x_limits(2)];
        end

        % Value changed function: XmaxEditField
        function XmaxEditFieldValueChanged(app, event)
            x_limits = app.UIAxes2.XLim;
            app.UIAxes2.XLim = [x_limits(1), app.XmaxEditField.Value];
        end

        % Value changed function: YminEditField
        function YminEditFieldValueChanged(app, event)
            y_limits = app.UIAxes2.YLim;
            app.UIAxes2.YLim = [app.YminEditField.Value, y_limits(2)];
        end

        % Value changed function: YmaxEditField
        function YmaxEditFieldValueChanged(app, event)
            y_limits = app.UIAxes2.YLim;
            app.UIAxes2.YLim = [y_limits(1), app.YmaxEditField.Value];            
        end

        % Value changed function: ZminEditField
        function ZminEditFieldValueChanged(app, event)
            z_limits = app.UIAxes2.ZLim;
            app.UIAxes2.ZLim = [app.ZminEditField.Value, z_limits(2)];            
        end

        % Value changed function: ZmaxEditField
        function ZmaxEditFieldValueChanged(app, event)
            z_limits = app.UIAxes2.ZLim;
            app.UIAxes2.ZLim = [z_limits(1), app.ZmaxEditField.Value];            
        end

        % Value changed function: XminEditField_2
        function XminEditField_2ValueChanged(app, event)
            x_limits = app.UIAxes.XLim;
            app.UIAxes.XLim = [app.XminEditField_2.Value, x_limits(2)];            
        end

        % Value changed function: YminEditField_2
        function YminEditField_2ValueChanged(app, event)
            y_limits = app.UIAxes.YLim;
            app.UIAxes.YLim = [app.YminEditField_2.Value, y_limits(2)];
        end

        % Value changed function: ZminEditField_2
        function ZminEditField_2ValueChanged(app, event)
             z_limits = app.UIAxes.ZLim;
             app.UIAxes.ZLim = [app.ZminEditField_2.Value, z_limits(2)]; 
        end

        % Value changed function: XmaxEditField_2
        function XmaxEditField_2ValueChanged(app, event)
             x_limits = app.UIAxes.XLim;
            app.UIAxes.XLim = [x_limits(1), app.XmaxEditField_2.Value];            
        end

        % Value changed function: YmaxEditField_2
        function YmaxEditField_2ValueChanged(app, event)
            y_limits = app.UIAxes.YLim;
            app.UIAxes.YLim = [y_limits(1), app.YmaxEditField_2.Value];             
        end

        % Value changed function: ZmaxEditField_2
        function ZmaxEditField_2ValueChanged(app, event)
            z_limits = app.UIAxes.ZLim;
            app.UIAxes.ZLim = [z_limits(1), app.ZmaxEditField_2.Value];              
        end

        % Button pushed function: ApplyLimitsButton
        function ApplyLimitsButtonPushed(app, event)
            app.UIAxes2.XLim = [app.XminEditField.Value, app.XmaxEditField.Value];
            app.UIAxes2.YLim = [app.YminEditField.Value, app.YmaxEditField.Value];
            app.UIAxes2.ZLim = [app.XminEditField.Value, app.ZmaxEditField.Value];
        end

        % Button pushed function: ApplyLimitsButton_2
        function ApplyLimitsButton_2Pushed(app, event)
            app.UIAxes.XLim = [app.XminEditField_2.Value, app.XmaxEditField_2.Value];
            app.UIAxes.YLim = [app.YminEditField_2.Value, app.YmaxEditField_2.Value];
            app.UIAxes.ZLim = [app.XminEditField_2.Value, app.ZmaxEditField_2.Value];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.STLFileLabel = uilabel(app.UIFigure);
            app.STLFileLabel.Text = 'STL File';
            app.textfield_stl_filename = uieditfield(app.UIFigure, 'text');
            app.textfield_stl_filename.Value = 'D638_TypeI.STL';
            app.button_browse_stl_filename = uibutton(app.UIFigure, 'push');
            app.button_browse_stl_filename.ButtonPushedFcn = createCallbackFcn(app, @button_browse_stl_filenamePushed, true);
            app.button_browse_stl_filename.Text = 'Browse';
            app.SliceheightEditFieldLabel = uilabel(app.UIFigure);
            app.SliceheightEditFieldLabel.Text = 'Slice height';
            app.numericField_slice_height = uieditfield(app.UIFigure, 'numeric');
            app.numericField_slice_height.Value = 0.4;
            app.SliceButton = uibutton(app.UIFigure, 'push');
            app.SliceButton.ButtonPushedFcn = createCallbackFcn(app, @SliceButtonPushed, true);
            app.SliceButton.Text = 'Slice';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'STL file')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ASCIIfileCheckBox = uicheckbox(app.UIFigure);
            app.ASCIIfileCheckBox.Text = 'ASCII file';
            app.RotationangleEditFieldLabel = uilabel(app.UIFigure);
            app.RotationangleEditFieldLabel.Text = 'Rotation angle';
            app.RotationangleEditField = uieditfield(app.UIFigure, 'numeric');
            app.RotationangleEditField.Limits = [0 360];
            app.RotationangleEditField.Value = 90;
            app.RotateButton = uibutton(app.UIFigure, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.RotationaxisButtonGroup = uibuttongroup(app.UIFigure);
            app.RotationaxisButtonGroup.Title = 'Rotation axis';
            app.xButton = uiradiobutton(app.RotationaxisButtonGroup);
            app.xButton.Text = 'x';
            app.xButton.Value = true;
            app.yButton = uiradiobutton(app.RotationaxisButtonGroup);
            app.yButton.Text = 'y';
            app.zButton = uiradiobutton(app.RotationaxisButtonGroup);
            app.zButton.Text = 'z';
            app.ReadFileButton = uibutton(app.UIFigure, 'push');
            app.ReadFileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadFileButtonPushed, true);
            app.ReadFileButton.Text = 'ReadFile';
            app.PanButton = uibutton(app.UIFigure, 'push');
            app.PanButton.ButtonPushedFcn = createCallbackFcn(app, @PanButtonPushed, true);
            app.PanButton.Text = 'Pan';
            app.ZoomButton = uibutton(app.UIFigure, 'push');
            app.ZoomButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomButtonPushed, true);
            app.ZoomButton.Text = 'Zoom';
            app.RotateButton_2 = uibutton(app.UIFigure, 'push');
            app.RotateButton_2.ButtonPushedFcn = createCallbackFcn(app, @RotateButton_2Pushed, true);
            app.RotateButton_2.Text = 'Rotate';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Sliced')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.PanButton_2 = uibutton(app.UIFigure, 'push');
            app.PanButton_2.ButtonPushedFcn = createCallbackFcn(app, @PanButton_2Pushed, true);
            app.PanButton_2.Text = 'Pan';
            app.ZoomButton_2 = uibutton(app.UIFigure, 'push');
            app.ZoomButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZoomButton_2Pushed, true);
            app.ZoomButton_2.Text = 'Zoom';
            app.RotateButton_3 = uibutton(app.UIFigure, 'push');
            app.RotateButton_3.ButtonPushedFcn = createCallbackFcn(app, @RotateButton_3Pushed, true);
            app.RotateButton_3.Text = 'Rotate';
            app.PlotdelayEditFieldLabel = uilabel(app.UIFigure);
            app.PlotdelayEditFieldLabel.Text = 'Plot delay';
            app.PlotdelayEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotdelayEditField.Limits = [0 360];
            app.PlotdelayEditField.Value = 0.0001;
            app.XminEditFieldLabel = uilabel(app.UIFigure);
            app.XminEditFieldLabel.Text = 'Xmin';
            app.XminEditField = uieditfield(app.UIFigure, 'numeric');
            app.XminEditField.Limits = [0 360];
            app.XminEditField.ValueChangedFcn = createCallbackFcn(app, @XminEditFieldValueChanged, true);
            app.YminEditFieldLabel = uilabel(app.UIFigure);
            app.YminEditFieldLabel.Text = 'Ymin';
            app.YminEditField = uieditfield(app.UIFigure, 'numeric');
            app.YminEditField.Limits = [0 360];
            app.YminEditField.ValueChangedFcn = createCallbackFcn(app, @YminEditFieldValueChanged, true);
            app.ZminEditFieldLabel = uilabel(app.UIFigure);
            app.ZminEditFieldLabel.Text = 'Zmin';
            app.ZminEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZminEditField.Limits = [0 360];
            app.ZminEditField.ValueChangedFcn = createCallbackFcn(app, @ZminEditFieldValueChanged, true);
            app.XmaxEditFieldLabel = uilabel(app.UIFigure);
            app.XmaxEditFieldLabel.Text = 'Xmax';
            app.XmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.XmaxEditField.Limits = [0 360];
            app.XmaxEditField.ValueChangedFcn = createCallbackFcn(app, @XmaxEditFieldValueChanged, true);
            app.XmaxEditField.Value = 170;
            app.YmaxEditFieldLabel = uilabel(app.UIFigure);
            app.YmaxEditFieldLabel.Text = 'Ymax';
            app.YmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.YmaxEditField.Limits = [0 360];
            app.YmaxEditField.ValueChangedFcn = createCallbackFcn(app, @YmaxEditFieldValueChanged, true);
            app.YmaxEditField.Value = 25;
            app.ZmaxEditFieldLabel = uilabel(app.UIFigure);
            app.ZmaxEditFieldLabel.Text = 'Zmax';
            app.ZmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZmaxEditField.Limits = [0 360];
            app.ZmaxEditField.ValueChangedFcn = createCallbackFcn(app, @ZmaxEditFieldValueChanged, true);
            app.ZmaxEditField.Value = 45;
            app.XminEditField_2Label = uilabel(app.UIFigure);
            app.XminEditField_2Label.Text = 'Xmin';
            app.XminEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.XminEditField_2.Limits = [-10 360];
            app.XminEditField_2.ValueChangedFcn = createCallbackFcn(app, @XminEditField_2ValueChanged, true);
            app.YminEditField_2Label = uilabel(app.UIFigure);
            app.YminEditField_2Label.Text = 'Ymin';
            app.YminEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YminEditField_2.Limits = [-10 360];
            app.YminEditField_2.ValueChangedFcn = createCallbackFcn(app, @YminEditField_2ValueChanged, true);
            app.ZminEditField_2Label = uilabel(app.UIFigure);
            app.ZminEditField_2Label.Text = 'Zmin';
            app.ZminEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ZminEditField_2.Limits = [-10 360];
            app.ZminEditField_2.ValueChangedFcn = createCallbackFcn(app, @ZminEditField_2ValueChanged, true);
            app.XmaxEditField_2Label = uilabel(app.UIFigure);
            app.XmaxEditField_2Label.Text = 'Xmax';
            app.XmaxEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.XmaxEditField_2.Limits = [0 360];
            app.XmaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @XmaxEditField_2ValueChanged, true);
            app.XmaxEditField_2.Value = 170;
            app.YmaxEditField_2Label = uilabel(app.UIFigure);
            app.YmaxEditField_2Label.Text = 'Ymax';
            app.YmaxEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YmaxEditField_2.Limits = [0 360];
            app.YmaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @YmaxEditField_2ValueChanged, true);
            app.YmaxEditField_2.Value = 50;
            app.ZmaxEditField_2Label = uilabel(app.UIFigure);
            app.ZmaxEditField_2Label.Text = 'Zmax';
            app.ZmaxEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ZmaxEditField_2.Limits = [0 360];
            app.ZmaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @ZmaxEditField_2ValueChanged, true);
            app.ZmaxEditField_2.Value = 45;
            app.ApplyLimitsButton = uibutton(app.UIFigure, 'push');
            app.ApplyLimitsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyLimitsButtonPushed, true);
            app.ApplyLimitsButton.Text = 'ApplyLimits';
            app.ApplyLimitsButton_2 = uibutton(app.UIFigure, 'push');
            app.ApplyLimitsButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyLimitsButton_2Pushed, true);
            app.ApplyLimitsButton_2.Text = 'ApplyLimits';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_stl_slice_and_plot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end